classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ModelDropDown       matlab.ui.control.DropDown
        ModelDropDownLabel  matlab.ui.control.Label
        PlotsPanel          matlab.ui.container.Panel
        UIAxes_4            matlab.ui.control.UIAxes
        UIAxes_2            matlab.ui.control.UIAxes
        UIAxes_3            matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
        LoadButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        Tb % Data table
        x % x coordinates
        y % y coordinates
        f % curve fit
    end
    
    methods (Access = private)
        
        function [Tb, isValid] = readExcelFile(~, file)
            Tb = readtable(file);
            vars = Tb.Properties.VariableNames;
            if ismember('x', vars) && ismember('y', vars)
                isValid = 1;
            else
                msgbox(sprintf('Choose a valid file%cA valid file contains a column for x data with title "x" and a column for y data with title "y"',...
                    newline),'Success', 'help');
                isValid = 0;
            end
        end
        
        function [x, y] = readData(app)
            vars = app.Tb.Properties.VariableNames;
            xCols = find(contains(vars,'x'));
            yCols = find(contains(vars,'y'));
            x = app.Tb{:,xCols};
            y = app.Tb{:,yCols};
        end
        
        function [Y, r] = linear(app)
            [Y, r] = linearModel(app.y, app.x);
            app.UIAxes.Title.String = "Linear model";
            app.UIAxes.XLabel.String = "X";
            app.UIAxes.YLabel.String = "Y";
            scatter(app.UIAxes, app.x, app.y);
            hold(app.UIAxes, 'on');
            xx  = linspace(app.x(1), app.x(end), 100);
            yy = interp1(app.x, Y, xx, 'cubic');
            plot(app.UIAxes, xx, yy);
            text(app.UIAxes, app.UIAxes.XTick(int32(end / 2)), app.UIAxes.YTick(int32(end / 2)), ['r =  ' num2str(r)]);
        end
        
        function [Y, r] = exponent(app)
            [Y, r] = expModel(app.y, app.x);
            app.UIAxes_2.Title.String = "Exponential model";
            app.UIAxes_2.XLabel.String = "X";
            app.UIAxes_2.YLabel.String = "Y";
            scatter(app.UIAxes_2, app.x, app.y);
            hold(app.UIAxes_2, 'on');
            xx  = linspace(app.x(1), app.x(end), 100);
            yy = interp1(app.x, Y, xx, 'cubic');
            plot(app.UIAxes_2, xx, yy);
            text(app.UIAxes_2, app.UIAxes_2.XTick(int32(end / 2)), app.UIAxes_2.YTick(int32(end / 2)), ['r =  ' num2str(r)]);
        end
        
        function [Y, r] = growthRate(app)
            [Y, r] = growthRateModel(app.y, app.x);
            app.UIAxes_3.Title.String = "Growth Rate model";
            app.UIAxes_3.XLabel.String = "X";
            app.UIAxes_3.YLabel.String = "Y";
            scatter(app.UIAxes_3, app.x, app.y);
            hold(app.UIAxes_3, 'on');
            xx  = linspace(app.x(1), app.x(end), 100);
            yy = interp1(app.x, Y, xx, 'cubic');
            plot(app.UIAxes_3, xx, yy);
            text(app.UIAxes_3, app.UIAxes_3.XTick(int32(end / 2)), app.UIAxes_3.YTick(int32(end / 2)), ['r =  ' num2str(r)]);
        end
        
        function [Y, r] = power(app)
            [Y, r] = powerModel(app.y, app.x);
            app.UIAxes_4.Title.String = "Power model";
            app.UIAxes_4.XLabel.String = "X";
            app.UIAxes_4.YLabel.String = "Y";
            scatter(app.UIAxes_4, app.x, app.y);
            hold(app.UIAxes_4, 'on');
            xx  = linspace(app.x(1), app.x(end), 100);
            yy = interp1(app.x, Y, xx, 'cubic');
            plot(app.UIAxes_4, xx, yy);
            text(app.UIAxes_4, app.UIAxes_4.XTick(int32(end / 2)), app.UIAxes_4.YTick(int32(end / 2)), ['r =  ' num2str(r)]);
        end
        
        function r = plots(app)
            [~, rLin] = linear(app);
            [~, rExp] = exponent(app);
            [~, rPow] = power(app);
            [~, rGr] = growthRate(app);
            
            r = [rLin, rExp, rPow, rGr];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PlotsPanel.Visible = 0;
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            position = app.UIFigure.Position;
            bP = app.LoadButton.Position;
            gP = app.PlotsPanel.Position;
            bP(1) = position(3) - 120;
            gP(3) = position(3) - 20;
            gP(4) = position(4) - 100;
            app.LoadButton.Position = bP;
            app.PlotsPanel.Position = gP;
            %disp(bP);
        end

        % Size changed function: PlotsPanel
        function PlotsPanelSizeChanged(app, event)
            position = app.PlotsPanel.Position;
            h = (position(4) - 20) / 4;
            UI1P = app.UIAxes.Position;
            UI2P = app.UIAxes_2.Position;
            UI3P = app.UIAxes_3.Position;
            UI4P = app.UIAxes_4.Position;
            
            UI1P(1) = 1;
            UI2P(1) = 1;
            UI3P(1) = 1;
            UI4P(1) = 1;
            UI1P(2) = 3 .* h;
            UI2P(2) = 2 .* h;
            UI3P(2) = h;
            UI4P(2) = 1;
            UI1P(3) = position(3);
            UI2P(3) = position(3);
            UI3P(3) = position(3);
            UI4P(3) = position(3);
            UI1P(4) = h;
            UI2P(4) = h;
            UI3P(4) = h;
            UI4P(4) = h;
            app.UIAxes.Position = UI1P;
            app.UIAxes_2.Position = UI2P;
            app.UIAxes_3.Position = UI3P;
            app.UIAxes_4.Position = UI4P;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            other = 1;
            while other
                [file,path] = uigetfile({'*.*';'*.csv';'*.xlsx';'*.mat'},...
                    'File Selector');
                data = fullfile(path, file);
                xlsx = contains(data,'.xlsx',IgnoreCase=true);
                csv = contains(data,'.csv',IgnoreCase=true);
                mat = contains(data,'.mat',IgnoreCase=true);
                type = [xlsx csv mat];
                switch find(type==1)
                    case 1 
                        [app.Tb, isValid] = readExcelFile(app, data);
                        if isValid
                            [app.x, app.y] = readData(app);
                            other = 0;
                        end
                    case 2
                        [app.Tb, isValid] = readExcelFile(app, data);
                        if isValid
                            [app.x, app.y] = readData(app);
                            other = 0;
                        end
                    case 3
                        d = load(data,"x","y");
                        app.x = d.x;
                        app.y = d.y;
                        other = 0;
                    otherwise
                        other = 1;
                end
            end
            app.PlotsPanel.Visible = 1;
            r = plots(app);
            model = bestSolution(r(1), r(2), r(3), r(4));
            switch model
                case 0
                    T = text(app.UIAxes, app.UIAxes.XTick(int32(end / 2)), app.UIAxes.YTick(int32(end / 2)), ['r =  ' num2str(r(1))]);
                    set(T, 'Color',[0, 1 ,0])
                case 1
                    T = text(app.UIAxes_2, app.UIAxes_2.XTick(int32(end / 2)), app.UIAxes_2.YTick(int32(end / 2)), ['r =  ' num2str(r(2))]);
                    set(T, 'Color',[0, 1 ,0])
                case 2
                    T = text(app.UIAxes_3, app.UIAxes_3.XTick(int32(end / 2)), app.UIAxes_3.YTick(int32(end / 2)), ['r =  ' num2str(r(3))]);
                    set(T, 'Color',[0, 1 ,0])
                case 3
                    T = text(app.UIAxes_4, app.UIAxes_4.XTick(int32(end / 2)), app.UIAxes_4.YTick(int32(end / 2)), ['r =  ' num2str(r(4))]);
                    set(T, 'Color',[0, 1 ,0])
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.PlotsPanel = uipanel(app.UIFigure);
            app.PlotsPanel.Title = 'Plots';
            app.PlotsPanel.SizeChangedFcn = createCallbackFcn(app, @PlotsPanelSizeChanged, true);
            app.UIAxes = uiaxes(app.PlotsPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_3 = uiaxes(app.PlotsPanel);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_2 = uiaxes(app.PlotsPanel);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_4 = uiaxes(app.PlotsPanel);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.ModelDropDownLabel = uilabel(app.UIFigure);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.UIFigure);
            app.ModelDropDown.Items = {};
            app.ModelDropDown.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end